classdef robot_arayuz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UITable              matlab.ui.control.Table
        Theta6Label          matlab.ui.control.Label
        Theta5Label          matlab.ui.control.Label
        Theta4Label          matlab.ui.control.Label
        Theta3Label          matlab.ui.control.Label
        Theta2Label          matlab.ui.control.Label
        Theta1Label          matlab.ui.control.Label
        leriKinematikButton  matlab.ui.control.Button
        Theta6Slider         matlab.ui.control.Slider
        Theta6SliderLabel    matlab.ui.control.Label
        Theta5Slider         matlab.ui.control.Slider
        Theta5SliderLabel    matlab.ui.control.Label
        Theta4Slider         matlab.ui.control.Slider
        Theta4SliderLabel    matlab.ui.control.Label
        Theta3Slider         matlab.ui.control.Slider
        Theta3SliderLabel    matlab.ui.control.Label
        Theta2Slider         matlab.ui.control.Slider
        Theta2SliderLabel    matlab.ui.control.Label
        Theta1Slider         matlab.ui.control.Slider
        Theta1SliderLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Theta1Slider
        function Theta1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta1Label.Text = num2str(changingValue);
%             a=app.Theta1Label.Value;
            app.EditField.Value=app.Theta1Slider.Value;
            a=app.EditField.Value;
%             mdl='KUKAKR_5R850';
%             open_system(mdl);
%             in=Simulink.SimulationInput(mdl);
%             in=in.setVariable('a',a);
%             out=sim('in');
        end

        % Value changing function: Theta2Slider
        function Theta2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta2Label.Text = num2str(changingValue);
        end

        % Value changing function: Theta3Slider
        function Theta3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta3Label.Text = num2str(changingValue);
        end

        % Value changing function: Theta4Slider
        function Theta4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta4Label.Text = num2str(changingValue);
        end

        % Value changing function: Theta5Slider
        function Theta5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta5Label.Text = num2str(changingValue);
        end

        % Value changing function: Theta6Slider
        function Theta6SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta6Label.Text = num2str(changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Theta1SliderLabel = uilabel(app.UIFigure);
            app.Theta1SliderLabel.Text = 'Theta1';
            app.Theta1Slider = uislider(app.UIFigure);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.ValueChangingFcn = createCallbackFcn(app, @Theta1SliderValueChanging, true);
            app.Theta2SliderLabel = uilabel(app.UIFigure);
            app.Theta2SliderLabel.Text = {'Theta2'; ''};
            app.Theta2Slider = uislider(app.UIFigure);
            app.Theta2Slider.Limits = [-180 180];
            app.Theta2Slider.ValueChangingFcn = createCallbackFcn(app, @Theta2SliderValueChanging, true);
            app.Theta3SliderLabel = uilabel(app.UIFigure);
            app.Theta3SliderLabel.Text = {'Theta3'; ''; ''};
            app.Theta3Slider = uislider(app.UIFigure);
            app.Theta3Slider.Limits = [-180 180];
            app.Theta3Slider.ValueChangingFcn = createCallbackFcn(app, @Theta3SliderValueChanging, true);
            app.Theta4SliderLabel = uilabel(app.UIFigure);
            app.Theta4SliderLabel.Text = {'Theta4'; ''};
            app.Theta4Slider = uislider(app.UIFigure);
            app.Theta4Slider.Limits = [-180 180];
            app.Theta4Slider.ValueChangingFcn = createCallbackFcn(app, @Theta4SliderValueChanging, true);
            app.Theta5SliderLabel = uilabel(app.UIFigure);
            app.Theta5SliderLabel.Text = 'Theta5';
            app.Theta5Slider = uislider(app.UIFigure);
            app.Theta5Slider.Limits = [-180 180];
            app.Theta5Slider.ValueChangingFcn = createCallbackFcn(app, @Theta5SliderValueChanging, true);
            app.Theta6SliderLabel = uilabel(app.UIFigure);
            app.Theta6SliderLabel.Text = 'Theta6';
            app.Theta6Slider = uislider(app.UIFigure);
            app.Theta6Slider.Limits = [-180 180];
            app.Theta6Slider.ValueChangingFcn = createCallbackFcn(app, @Theta6SliderValueChanging, true);
            app.leriKinematikButton = uibutton(app.UIFigure, 'push');
            app.leriKinematikButton.Text = {'İleri Kinematik'; ''};
            app.Theta1Label = uilabel(app.UIFigure);
            app.Theta1Label.Text = {'Theta1'; ''};
            app.Theta2Label = uilabel(app.UIFigure);
            app.Theta2Label.Text = {'Theta2'; ''};
            app.Theta3Label = uilabel(app.UIFigure);
            app.Theta3Label.Text = {'Theta3'; ''};
            app.Theta4Label = uilabel(app.UIFigure);
            app.Theta4Label.Text = {'Theta4'; ''};
            app.Theta5Label = uilabel(app.UIFigure);
            app.Theta5Label.Text = 'Theta5';
            app.Theta6Label = uilabel(app.UIFigure);
            app.Theta6Label.Text = {'Theta6'; ''};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Konum '; 'Sonuc'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_arayuz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end