classdef RobotUygulamasi_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TransformationMatrixoftheEndEffectorLabel  matlab.ui.control.Label
        JointValuesLabel         matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        AnimateButton            matlab.ui.control.Button
        DurationEditField        matlab.ui.control.NumericEditField
        DurationEditFieldLabel   matlab.ui.control.Label
        SaveFinalJointAnglesfromTableButton  matlab.ui.control.Button
        SaveInitialJointAnglesfromTableButton  matlab.ui.control.Button
        Theta3Label              matlab.ui.control.Label
        d2Label                  matlab.ui.control.Label
        Theta1Label              matlab.ui.control.Label
        UITable2                 matlab.ui.control.Table
        InverseKinematicsButton  matlab.ui.control.Button
        ForwardKinematicsButton  matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        Theta3Slider             matlab.ui.control.Slider
        Theta3SliderLabel        matlab.ui.control.Label
        d2Slider                 matlab.ui.control.Slider
        d2SliderLabel            matlab.ui.control.Label
        Theta1Slider             matlab.ui.control.Slider
        Theta1SliderLabel        matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

% Bu program Zafer Bingül hocanın Robot Tekniği adlı kitabındaki Örnek 3.2'nin doğruluğunu kontrol için başlatılıp,
% ters kinematik ve animasyon eklenerek son haline getirilmiştir. Kenan IŞIK tarafından hazırlanıp öğrencilerle paylaşılmıştır.

% Kod ekranında arka fonun gri olduğu yerler MATLAB'in otomatik oluşturduğu kodlardır. Arka fonun beyaz olduğu yerler
% programcı tarafından yazılmıştır.

% Program çalıştırıldığında eklem değişkenleri ilk değer olarak sıfır değerindedir. Sliderlar kullanılarak
% eklem değişkenleri değiştirilir ve robot konfigürasyonu sliderların yanındaki grafikte gözlemlenebilir.
% Bu işlemde kullanılan forwardKin fonksiyonu bu işlemi yaparken uç işlevciye ait dönüşüm matrisini de ekrandaki
% ilgili tabloda günceller (sağdaki tablo).

% Ekrandaki iki tablodaki değerler de kullanıcı tarafından değiştirilebilir. İstenilen değişiklikler yapıldıktan sonra
% "Forward Kinematics" butonu ile girilen eklem açıları için uç işlevcinin dönüşüm matrisi, veya "Inverse Kinematics"
% butonu ile uç işlevcinin dönüşüm matrisi için eklem açıları bulunabilir.

% Robotun iki nokta arasındaki hareketini animasyon olarak görmek için robot istenilen konfigurasyona getirilip
% "Save Initial Joint Angles from Table" butonu ile ilk posizyon bilgileri "Joint Values" tablosundan alınarak kaydedilir.
% Robotun son pozisyonu da tabloya eklem açıları yazılarak veya istenen konfigurasyondan ters kinematik fonksiyonu ile elde
% edildikten sonra "Save Final Joint Angles from Table" butonu ile kaydedilebilir. Daha sonra robotun ilk konfigürasyondan
% son konfigürasyona kaç saniyede gitmesi isteniyorsa "Duration" kısmına yazılır ve "Animate" butonuna tıklanarak animasyon
% gerçekleştirilir.

% v2 Notları:
% Ters kinematikle bulunan değerlerin doğruluğunu kontrol ederken oluşturulan fark matrislerinin çok küçük değerlerinin sıfıra
% eşitlenirken yapılan hata düzeltildi. Önceden 0.000001'den küçük sayılar sıfıra eşitleniyordu fakat bu durum negatif sayıların da
% sıfıra eşitlenmesine neden oluyordu. Yapılan değişiklikle abs() fonksiyonu kullanılarak sayıların mutlak değerleri çok küçük ise
% o sayı sıfıra eşitlenmektedir.
% abs() fonkisyonu ters kinematik işlemi sonunda elde edilen eklem değişkenlerinin kontrolünde de kullanılarak kod daha sade hale getirilmiştir.
% Bunun dışında ileri kinematik fonksiyonuna "vis" ve "table" argümanları eklenmiştir. Bunlar ileri kinematik fonksiyonu içinde
% gerçekleştirilen görselleştirme ve uc işlevcinin dönüşüm matrisini tabloya aktarma işlemlerini isteğe bağlı hale getirmiştir.
% Bu sayede forwardKin fonksiyonunun test için veya animasyon için inverseKin aracılığı ile çağırılması durumunda görselleştirme ve
% tabloya değer aktarımı iptal edilerek animasyonun çalışma hızı ilerletilmiştir.
% Ufak bir değişiklik olarak, başlangıç fonksiyonuna "format short" satırı eklenerek kullanıcı arayüzündeki sayıların daha okunaklı olması sağlanmıştır.

    methods (Access = private)
    
        function T04 = forwardKin(app, th1, d2, th3, vis, table)
            
            % Bu fonksiyon standard olan app parametresi ile üç eklem açısını giriş parametresi olarak alır ve uç işlevcinin dönüşüm matrisini dönderir.
            % Bu fonksiyona sonradan 'vis' argümanı eklenmiştir. Bu parametre, bu fonksiyon çağırılırken görselleştirme işleminin payılıp yapılmayacağını belirler. 1 ise grafik çizilir, 0 ise çizilmez.
            % Bu fonksiyona sonradan 'table' argümanı eklenmiştir. Bu parametre, bu fonksiyon çağırılırken 
            
            global h1 l2 l3         % Program başlatıldığında tanımlanan ve değerleri verilen robot parametreleri
            global l                % Ekranda oluşturulan çizginin atandığı değişken.
            global T01 T02 T03      % Robotun görselleştirilmeis için gereken dönüşüm matrisleri
                        
            DH =  [0	0	h1      th1;...
                   pi/2	0	d2+l2	pi/2;...
                   pi/2	0	0       th3;...
                   0	l3	0       0];         % DH parametrelerini barındıran matris


            % Aşağıda ileri kinematikte kullanılan dönüşüm matrisleri dört standart matrisin çarpılması ile elde edilmiştir.
            T01 = [1 0 0 0; 0 cos(DH(1,1)) -sin(DH(1,1)) 0; 0 sin(DH(1,1)) cos(DH(1,1)) 0; 0 0 0 1]*...
                  [1 0 0 DH(1,2); 0 1 0 0; 0 0 1 0; 0 0 0 1]*...
                  [cos(DH(1,4)) -sin(DH(1,4)) 0 0; sin(DH(1,4)) cos(DH(1,4)) 0 0; 0 0  1 0; 0 0 0 1]*...
                  [1 0 0 0; 0 1 0 0; 0 0 1 DH(1,3); 0 0 0 1];
              
            T12 = [1 0 0 0; 0 cos(DH(2,1)) -sin(DH(2,1)) 0; 0 sin(DH(2,1)) cos(DH(2,1)) 0; 0 0 0 1]*...
                  [1 0 0 DH(2,2); 0 1 0 0; 0 0 1 0; 0 0 0 1]*...
                  [cos(DH(2,4)) -sin(DH(2,4)) 0 0; sin(DH(2,4)) cos(DH(2,4)) 0 0; 0 0  1 0; 0 0 0 1]*...
                  [1 0 0 0; 0 1 0 0; 0 0 1 DH(2,3); 0 0 0 1];
              
            T23 = [1 0 0 0; 0 cos(DH(3,1)) -sin(DH(3,1)) 0; 0 sin(DH(3,1)) cos(DH(3,1)) 0; 0 0 0 1]*...
                  [1 0 0 DH(3,2); 0 1 0 0; 0 0 1 0; 0 0 0 1]*...
                  [cos(DH(3,4)) -sin(DH(3,4)) 0 0; sin(DH(3,4)) cos(DH(3,4)) 0 0; 0 0  1 0; 0 0 0 1]*...
                  [1 0 0 0; 0 1 0 0; 0 0 1 DH(3,3); 0 0 0 1];
              
            T34 = [1 0 0 0; 0 cos(DH(4,1)) -sin(DH(4,1)) 0; 0 sin(DH(4,1)) cos(DH(4,1)) 0; 0 0 0 1]*...
                  [1 0 0 DH(4,2); 0 1 0 0; 0 0 1 0; 0 0 0 1]*...
                  [cos(DH(4,4)) -sin(DH(4,4)) 0 0; sin(DH(4,4)) cos(DH(4,4)) 0 0; 0 0  1 0; 0 0 0 1]*...
                  [1 0 0 0; 0 1 0 0; 0 0 1 DH(4,3); 0 0 0 1];
            
            T02 = T01*T12;
            T03 = T02*T23;
            T04 = T03*T34;
            
            % Robot konfigürasyonunun gösteriminde kullanılan eksenin özellikleri burda belirlenmiştir.
            if vis == 1
                ax = app.UIAxes;
                ax.XLim = [-6 6];
                ax.YLim = [-6 6];
                ax.ZLim = [0 5];
                ax.Box = 'on';
                ax.View = [-60 80]; % Bu satır üç boyutlu grafiğe hangi açıdan bakacağınızı belirtir.
%                 camproj(ax ,'perspective');
                
                % Aşağıdaki döngü ile uç işlevcinin dönüşüm matrisindeki sıfıra çok yakın olan değerler sıfıra eşitlenmiştir.
                % Normalde sıfır çıkması gereken değerler yapılan işlemlerdeki hassasiyet nedeni ile sıfırdan farklı çıkabilmektedir.
                for i = 1:4
                    for j = 1:4
                        if T04(i,j) < 0.000001 && T04(i,j) > -0.000001
                            T04(i,j) = 0;
                        end
                    end
                end
                
               % Robot konfigurasyonunun görselleştirilmesi için koordinat sistemlerinin merkezlerini veren x, y ve z değerleri vektörlere atanmıştır.
                x = [0 T01(1,4) T02(1,4) T03(1,4) T04(1,4)];
                y = [0 T01(2,4) T02(2,4) T03(2,4) T04(2,4)];
                z = [0 T01(3,4) T02(3,4) T03(3,4) T04(3,4)];
                
                l = line(app.UIAxes, x,y,z); % Üç boyutlu eksende robot çizgi olarak oluşturulur.
            end
            
            if table == 1
                app.UITable.Data = T04; % Uç işlevcinin dönüşüm matrisi tabloya aktarılır.
            end
            
        end
        
        function results = inverseKin(app)
            
            % Bu fonksiyon, uç işlevcinin dönüşüm matrisini veren tablodaki değerleri kullanarak ters kinematik işlemini gerçekleştirir ve bulduğu
            % doğru çözüm kümesini "Joint Values" tablosuna yazar.
            
            global l2 l3 l;
            delete(l);              % Ters kinematik işlemi yapılan robot konfigürasyonu görselleştirileceği için önceki robot konfigürasyonu silinir.
            T = app.UITable.Data; % Dönüşüm matrisini veren tablodaki veriler ile T adlı dönüşüm matrisi oluşturulur.
            
            % Bu robotun ters kinematik denklemleri, eşitliğin iki tarafının da önden önce T01'in tersi, sonra T12'nin tersi ve son olarak T23'ün tersi
            % ile çarpılarak elde edilebilmiştir.
            
            
            theta1_1 = atan2(-T(1,4),T(2,4));    % Birinci theta 1 değeri
            
            theta1_2 = atan2(T(1,4),-T(2,4));     % İkinci theta 1 değeri
            
            a1 = T(1,1)*sin(theta1_1) - T(2,1)*cos(theta1_1);
            a2 = T(1,1)*sin(theta1_2) - T(2,1)*cos(theta1_2);
            b = T(3,1);
            c = 1;
            
            d1 = abs(sqrt((a1^2)+(b^2)-(c^2)));     % Burada abs() fonksiyonunun kullanılmasının sebebi d1 ve d2'nin bazı eklem açılarında karmaşık değer almasıdır.
            d2 = abs(sqrt((a2^2)+(b^2)-(c^2)));     %  Bunu önlemek için abs() fonksiyonu kullanılmıştır.
            
            theta3_1 = atan2(a1,b) + atan2(d1,c);    % Birinci theta 1 ile elde edilen birinci theta 3 değeri (birinci theta 3)
            theta3_2 = atan2(a1,b) + atan2(-d1,c);    % Birinci theta 1 ile elde edilen ikinci theta 3 değeri (ikinci theta 3)
            theta3_3 = atan2(a2,b) + atan2(d2,c);     % İkinci theta 1 ile elde edilen birinci theta 3 değeri (üçüncü theta 3)
            theta3_4 = atan2(a2,b) + atan2(-d2,c);  % İkinci theta 1 ile elde edilen ikinci theta 3 değeri (dördüncü theta 3)
            
            dd21 = sin(theta1_1)*T(1,4) - cos(theta1_1)*T(2,4) - l3*sin(theta3_1) - l2;    % Birinci theta 1 ve birinci theta 3 ile elde edilen d2 değeri
            dd22 = sin(theta1_1)*T(1,4) - cos(theta1_1)*T(2,4) - l3*sin(theta3_2) - l2;     % Birinci theta 1 ve ikinci theta 3 ile elde edilen d2 değeri
            dd23 = sin(theta1_2)*T(1,4) - cos(theta1_2)*T(2,4) - l3*sin(theta3_3) - l2;       % İkinci theta 1 ve üçüncü theta 3 ile elde edilen d2 değeri
            dd24 = sin(theta1_2)*T(1,4) - cos(theta1_2)*T(2,4) - l3*sin(theta3_4) - l2;     % İkinci theta 1 ve dördüncü theta 3 ile elde edilen d2 değeri
            
            % Sıfır çıkması gerekirken sıfıra çok yakın çıkan eklem değerlerini sıfıra eşitlenmiştir.
            if abs(theta1_1) < 0.00001
                theta1_1 = 0;
            end
            
            if abs(theta1_2) < 0.00001
                theta1_2 = 0;
            end
            
            if abs(theta3_1) < 0.00001
                theta3_1 = 0;
            end
            
            if abs(theta3_2) < 0.00001
                theta3_2 = 0;
            end
            
            if abs(theta3_3) < 0.00001
                theta3_3 = 0;
            end
            
            if abs(theta3_4) < 0.00001
                theta3_4 = 0;
            end
            
            if abs(dd21) < 0.00001
                dd21 = 0;
            end
            
            if abs(dd22) < 0.00001
                dd22 = 0;
            end
            
            if abs(dd23) < 0.00001
                dd23 = 0;
            end
            
            if abs(dd24) < 0.00001
                dd24 = 0;
            end
            
            % Bulunan eklem değişkeni gurupları ileri kinematik fonksiyonu ile denenerek uç işlevcinin dönüşüm matrisleri değişkenlere atanmıştır.
            T1 = forwardKin(app, theta1_1,dd21,theta3_1, 0, 0);
            delete(l);
            T2 = forwardKin(app, theta1_1,dd22,theta3_2, 0, 0);
            delete(l);
            T3 = forwardKin(app, theta1_2,dd23,theta3_3, 0, 0);
            delete(l);
            T4 = forwardKin(app, theta1_2,dd24,theta3_4, 0, 0);
            delete(l);
            
            % Birinci eklem değişkeni gurubunun (olası çözüm kümesi) ileri kinematikle verdiği uç işlevci dönüşüm matrisi, giriş parametresi olarak kullanılan dönüşüm
            % matrisinden çılarılarak A matrisine atanmıştır. Eğer bize verilen uç işlevci konfigürasyonunun aynısını veren eklem değişkeni gurubunu
            % buldu isek, A matrisinin elemanları sıfır değerini almalıdır. (Veya sıfıra çok yakın değerler. Fark matrisindeki sıfıra çok yakın değerler
            % sıfıra eşitlenerek kontrol işlemi kolaylaştırılmıştır.
            A = T-T1;
            A(abs(A) < 0.00001) = 0;
%            A(A > -0.00001) = 0;
            
            % İkinci eklem değişkeni gurubu için aynı işlem tekrarlanmıştır.
            B = T-T2;
            B(abs(B) < 0.00001) = 0;
%            B(B > -0.00001) = 0;
            
            % Üçüncü eklem değişkeni gurubu için aynı işlem tekrarlanmıştır.
            C = T-T3;
            C(abs(C) < 0.00001) = 0;
%            C(C > -0.00001) = 0;
            
            % Dördüncü eklem değişkeni gurubu için aynı işlem tekrarlanmıştır.
            D = T-T4;
            D(abs(D) < 0.00001) = 0;
%            D(D > -0.00001) = 0;
            
            
            if A == 0                               % Eğer A matrisinin elemanları sıfır ise
                if dd21 >= 0                        % Ve d2 değişkeni negatif bir değer değil ise
                    ck = [theta1_1 dd21 theta3_1];  % Doğru çözüm kümesi (ck) theta1_1, dd21 ve theta3_1'den oluşmaktadır.
                end
            end
            if B == 0
                if dd22 >= 0
                    ck = [theta1_1 dd22 theta3_2];  % Aynı kontrol ikinci gurup için de yapılır. Doğru sonuç veriyor ise çözüm kümesi güncellenir.
                end
            end
            if C == 0
                if dd23 >= 0
                    ck = [theta1_2 dd23 theta3_3];
                end
            end
            if D == 0
                if dd24 >=0
                    ck = [theta1_2 dd24 theta3_4];
                end
            end
            
            % Doğru eklem değerlerini veren çözüm kümesi ile ileri kinematik fonksiyonu çalıştırılır ve robot görseli güncellenir.
            forwardKin(app, ck(1,1), ck(1,2), ck(1,3), 1, 1);
            % Bulunan doğru eklem değişkeni değerleri (açı olanlar radyandan dereceye dönüştürülerek) tabloda gösterilir.
            app.UITable2.Data = {'Theta 1' rad2deg(ck(1,1)); 'd2' ck(1,2); 'Theta3' rad2deg(ck(1,3))};
                
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Program ilk başladığında çalışan fonksiyon. Robot sabitlerini global değişkenler olarak belirler.
            % Başlangıç eksen değerleri sliderlardan alınıp tabloya aktarılır. 
            % Başlangıç eksen değerleri ile ileri kinematik fonksiyonu çalıştırılıp robot görseli oluşturulur.
            
            format short % Verilerin tablolarda okunaklı olarak görünmesi için virgülden sonraki hane sayısı sınırlandırılmıştır.
            
            global h1 l2 l3
            h1 = 3;
            l2 = 1;
            l3 = 1;
            app.UITable2.Data = {'Theta 1' app.Theta1Slider.Value; 'd2' app.d2Slider.Value; 'Theta3' app.Theta3Slider.Value};
            forwardKin(app, app.Theta1Slider.Value, app.d2Slider.Value, app.Theta3Slider.Value, 1, 1)
            
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
            % Slider değeri değişince ileri kinematik fonksiyonu ile robot görseli güncellenir. Aynı fonksiyon d2 ve theta3 sliderı için de vardır.
            global l;
            delete(l);
            forwardKin(app, deg2rad(app.Theta1Slider.Value), app.d2Slider.Value, deg2rad(app.Theta3Slider.Value), 1, 1);
        end

        % Value changing function: Theta1Slider
        function Theta1SliderValueChanging(app, event)
            % Slider değeri değişirken mevcut değer sliderın üstündeki kısımda gösterilir. Aynı fonksiyon d2 ve theta3 sliderı için de vardır.
            changingValue = event.Value;
            app.Theta1Label.Text = num2str(changingValue);
        end

        % Value changing function: d2Slider
        function d2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.d2Label.Text = num2str(changingValue);
        end

        % Value changing function: Theta3Slider
        function Theta3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta3Label.Text = num2str(changingValue);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: d2Slider
        function d2SliderValueChanged(app, event)
            global l;
            delete(l);
            forwardKin(app, deg2rad(app.Theta1Slider.Value), app.d2Slider.Value, deg2rad(app.Theta3Slider.Value), 1, 1);
        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
            global l;
            delete(l);
            forwardKin(app, deg2rad(app.Theta1Slider.Value), app.d2Slider.Value, deg2rad(app.Theta3Slider.Value), 1, 1);
        end

        % Button pushed function: ForwardKinematicsButton
        function ForwardKinematicsButtonPushed(app, event)
            % Forward Kinematics butonuna basılınca eklem açılarını veren tablodan değerler alınarak ve bunlar kullanılarak ileri kinematik fonksiyonu çalıştırılır.
            global l;
            delete(l);
            data = app.UITable2.Data;
            forwardKin(app, deg2rad(cell2mat(data(1,2))), cell2mat(data(2,2)), deg2rad(cell2mat(data(3,2))), 1, 1)
        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            % Inverse Kinematics butonuna basılınca ters kinematik fonksiyonu çalıştırılır.
            global l;
            delete(l);
            inverseKin(app);
        end

        % Button pushed function: 
        % SaveInitialJointAnglesfromTableButton
        function SaveInitialJointAnglesfromTableButtonPushed(app, event)
            % Eklem değişkenleri tablodan alınarak animasyonda kullanılacak ilk pozisyon global değerler olarak kaydedilir ve butonun altına kaydedilen değerler yazılır.
            
            global th1_i d2_i th3_i
            
            data = app.UITable2.Data;
                        
            th1_i = cell2mat(data(1,2));
            d2_i = cell2mat(data(2,2));
            th3_i = cell2mat(data(3,2));
            
            initial_joint_values = sprintf('Initial Position: th1_i = %.2f, d2_i = %.2f, th3_i = %.2f', th1_i, d2_i, th3_i);
                        
            app.Label.Text = initial_joint_values;
            
        end

        % Button pushed function: 
        % SaveFinalJointAnglesfromTableButton
        function SaveFinalJointAnglesfromTableButtonPushed(app, event)
            % Eklem değişkenleri tablodan alınarak animasyonda kullanılacak son pozisyon global değerler olarak kaydedilir ve butonun altına kaydedilen değerler yazılır.
            global th1_f d2_f th3_f
            
            data = app.UITable2.Data;
                        
            th1_f = cell2mat(data(1,2));
            d2_f = cell2mat(data(2,2));
            th3_f = cell2mat(data(3,2));
            
            final_joint_values = sprintf('Final Position: th1_f = %.2f, d2_f = %.2f, th3_f = %.2f', th1_f, d2_f, th3_f);
                        
            app.Label_2.Text = final_joint_values;
            
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            % Kaydedilen ilk ve son eklem açılarını kullanarak yörünge planlaması yapar ve animasyon olarak gösterir.
            
            global th1_i d2_i th3_i th1_f d2_f th3_f
            global T01 T02 T03
            global l
            
            delete(l);  % Önceden kalma robot konfigürasyonu silinir.
            
            t_f = app.DurationEditField.Value;  % Robotun başlangıç pozisyonundan biriş posizyonuna ne kadar sürede ulaşması gerektiği kaydedilir.
            
            % Birinci eklem için konum polinomumun katsayıları hesaplanır.
            s0_th1 = th1_i;
            s1_th1 = 0;
            s2_th1 = (3*(th1_f-th1_i))/t_f^2;
            s3_th1 = -(2*(th1_f-th1_i))/t_f^3;
            
            % İkinci eklem için konum polinomumun katsayıları hesaplanır.
            s0_d2 = d2_i;
            s1_d2 = 0;
            s2_d2 = (3*(d2_f-d2_i))/t_f^2;
            s3_d2 = -(2*(d2_f-d2_i))/t_f^3;
            
            % Üçüncü eklem için konum polinomumun katsayıları hesaplanır.
            s0_th3 = th3_i;
            s1_th3 = 0;
            s2_th3 = (3*(th3_f-th3_i))/t_f^2;
            s3_th3 = -(2*(th3_f-th3_i))/t_f^3;
            
            % Robot göreselinin ne kadar sıklıkla güncelleneceği belirlenir. Bu sayı küçüldükte işlem yükü artacaktır. Mevut sayı ile bir saniyelik hareket 10+1 "kare"de gösterilecektir.
            step = 0.1;
            
            % Animasyon süreci eşit parçalara ayrılır.
            t=0:step:t_f;
            
            % Herbir ara noktadaki eklem değerlerinin saklanacağı değişkenler oluşturulur.
            th1_points = zeros(1,length(t));
            d2_points = zeros(1,length(t));
            th3_points = zeros(1,length(t));
            
            % Konum denklemleri oluşturulup, her bir zaman noktasında eklemin alacağı açı belirlenir.
            for i=1:length(t)
                th1_points(i) = s0_th1 + s1_th1*t(i) + s2_th1*t(i)^2 + s3_th1*t(i)^3;
            end
            
            for i=1:length(t)
                d2_points(i) = s0_d2 + s1_d2*t(i) + s2_d2*t(i)^2 + s3_d2*t(i)^3;
            end
            
            for i=1:length(t)
                th3_points(i) = s0_th3 + s1_th3*t(i) + s2_th3*t(i)^2 + s3_th3*t(i)^3;
            end
            
            % Bilgisayarın kasılma ihtimali göz önünde bulundurularak robotun ilk konfigürasyonundan son konfigürasyonuna gidene kadar oluşacak ara konfigürasyonlar
            % önce hazırlanıp sonra animasyon çalıştırılacaktır.
            
            % Robotun başlangıç konfigürasyonundan son konfigürasyonuna kadar bütün ara konfigürasyonlarındaki koordinat sistemlerinin merkezlerinin x, y ve z değerlerinin
            % yazılacağı değişkenler belirlenmiştir.
            x_positions = zeros(length(t),5);
            y_positions = zeros(length(t),5);
            z_positions = zeros(length(t),5);
            
            % Başlangıç açılarından bitiş açılarına kadar tüm açı değerleri ileri kinematik fonksiyonunda çalıştırılarak koordinat sistemlerinin konumları belirlenir ve kaydedilir.
            for i=1:length(t)
                T04 = forwardKin(app, deg2rad(th1_points(i)), d2_points(i), deg2rad(th3_points(i)), 0, 0);
                delete(l);
                x_positions(i,:) = [0 T01(1,4) T02(1,4) T03(1,4) T04(1,4)];
                y_positions(i,:) = [0 T01(2,4) T02(2,4) T03(2,4) T04(2,4)];
                z_positions(i,:) = [0 T01(3,4) T02(3,4) T03(3,4) T04(3,4)];
                
            end
            
            % Robot konfigürasyonuna göre bulunan ve kaydedilen konumlar kullanılarak animasyon gerçekleştirilir.
            for i=1:length(t)
                delete(l);
                l = line(app.UIAxes, x_positions(i,:), y_positions(i,:), z_positions(i,:));
                
                drawnow
                
                pause(0.05); % Animasyonun izlenebilir olması için her adımda kısa bir süre beklenir.
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Position')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Theta1SliderLabel = uilabel(app.UIFigure);
            app.Theta1SliderLabel.Text = 'Theta 1';
            app.Theta1Slider = uislider(app.UIFigure);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta1Slider.ValueChangingFcn = createCallbackFcn(app, @Theta1SliderValueChanging, true);
            app.d2SliderLabel = uilabel(app.UIFigure);
            app.d2SliderLabel.Text = 'd2';
            app.d2Slider = uislider(app.UIFigure);
            app.d2Slider.Limits = [0 4];
            app.d2Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4];
            app.d2Slider.ValueChangedFcn = createCallbackFcn(app, @d2SliderValueChanged, true);
            app.d2Slider.ValueChangingFcn = createCallbackFcn(app, @d2SliderValueChanging, true);
            app.Theta3SliderLabel = uilabel(app.UIFigure);
            app.Theta3SliderLabel.Text = {'Theta 3'; ''};
            app.Theta3Slider = uislider(app.UIFigure);
            app.Theta3Slider.Limits = [-180 180];
            app.Theta3Slider.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.Theta3Slider.ValueChangingFcn = createCallbackFcn(app, @Theta3SliderValueChanging, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'n'; 's'; 'a'; 'p'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ForwardKinematicsButton = uibutton(app.UIFigure, 'push');
            app.ForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsButtonPushed, true);
            app.ForwardKinematicsButton.Text = 'Forward Kinematics';
            app.InverseKinematicsButton = uibutton(app.UIFigure, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Joint Number'; 'Joint Value'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false true];
            app.Theta1Label = uilabel(app.UIFigure);
            app.Theta1Label.Text = 'Theta 1';
            app.d2Label = uilabel(app.UIFigure);
            app.d2Label.Text = 'd 2';
            app.Theta3Label = uilabel(app.UIFigure);
            app.Theta3Label.Text = 'Theta 3';
            app.SaveInitialJointAnglesfromTableButton = uibutton(app.UIFigure, 'push');
            app.SaveInitialJointAnglesfromTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInitialJointAnglesfromTableButtonPushed, true);
            app.SaveInitialJointAnglesfromTableButton.Text = 'Save Initial Joint Angles from Table';
            app.SaveFinalJointAnglesfromTableButton = uibutton(app.UIFigure, 'push');
            app.SaveFinalJointAnglesfromTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFinalJointAnglesfromTableButtonPushed, true);
            app.SaveFinalJointAnglesfromTableButton.Text = 'Save Final Joint Angles from Table';
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.Value = 2;
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.JointValuesLabel = uilabel(app.UIFigure);
            app.JointValuesLabel.Text = 'Joint Values';
            app.TransformationMatrixoftheEndEffectorLabel = uilabel(app.UIFigure);
            app.TransformationMatrixoftheEndEffectorLabel.Text = 'Transformation Matrix of the End Effector';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotUygulamasi_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end